classdef music < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Button_5           matlab.ui.control.Button
        Button_4           matlab.ui.control.Button
        Button_3           matlab.ui.control.Button
        Button_2           matlab.ui.control.Button
        shiftRatio         matlab.ui.control.NumericEditField
        Label_5            matlab.ui.control.Label
        bpm                matlab.ui.control.NumericEditField
        bpmEditFieldLabel  matlab.ui.control.Label
        baseTune           matlab.ui.control.NumericEditField
        Label_4            matlab.ui.control.Label
        playfile           matlab.ui.control.EditField
        Label_3            matlab.ui.control.Label
        Button             matlab.ui.control.Button
        harmonic           matlab.ui.control.EditField
        Label              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
            v1 = app.EditField_1.Value;
            v2 = app.Fs.Value;
            res = v1 + v2;
            app.Label_3.Text = num2str(res);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [file, wavpath] = uigetfile("*.wav");
            if file ~= 0
                app.harmonic.Value = fullfile(wavpath, file);
            end
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            extract_harmonics(app.harmonic.Value, app);
            fig = uifigure;
            selection = uiconfirm(fig, "音频提取成功！", 'OK', 'Options',{'OK'});
            delete(fig);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            [file, jsonpath] = uigetfile("*.json");
            if file ~= 0
                app.playfile.Value = fullfile(jsonpath, file);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            play_music(app.playfile.Value, app.baseTune.Value, app.bpm.Value, app.shiftRatio.Value);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            clear sound;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '提取谐波音频文件：';
            app.harmonic = uieditfield(app.UIFigure, 'text');
            app.harmonic.Value = 'fmt.wav';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Text = '提取';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '待播放文件：';
            app.playfile = uieditfield(app.UIFigure, 'text');
            app.playfile.Value = 'eastred.json';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '升降调(半音数，0为C大调)';
            app.baseTune = uieditfield(app.UIFigure, 'numeric');
            app.bpmEditFieldLabel = uilabel(app.UIFigure);
            app.bpmEditFieldLabel.Text = 'bpm';
            app.bpm = uieditfield(app.UIFigure, 'numeric');
            app.bpm.Value = 120;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '叠音效果';
            app.shiftRatio = uieditfield(app.UIFigure, 'numeric');
            app.shiftRatio.Value = 1.1;
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '选择路径';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '选择路径';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '播放';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '停止';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = music

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end